classdef final_prj_KN301_Artsybashev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PointY            matlab.ui.control.NumericEditField
        YLabel            matlab.ui.control.Label
        PointX            matlab.ui.control.NumericEditField
        XLabel            matlab.ui.control.Label
        Quantity          matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        Intensity         matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        Step              matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        ParamB            matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        PointLbl          matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Title             matlab.ui.control.Label
        Graph             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            paramB = double(app.ParamB.Value);
            quantity = int32(app.Quantity.Value);
            h = double(app.Step.Value);
            intensity = double(app.Intensity.Value);
            startPoint = Point( ...
                double(app.PointX.Value), ...
                double(app.PointY.Value));
            [X, Y] = generatePointsOfStochasticTrajectories( ...
                paramB, ...
                startPoint, ...
                quantity, ...
                intensity, ...
                h);
            plot(app.Graph, X, Y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = {'Компьютерное моделирование стохастических траекторий'; 'модели Селькова-Строгаца при α = 0.1'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.PointLbl = uilabel(app.UIFigure);
            app.PointLbl.Text = 'Стартовая точка';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Параметр β';
            app.ParamB = uieditfield(app.UIFigure, 'numeric');
            app.ParamB.Value = 0.4199;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Шаг схемы';
            app.Step = uieditfield(app.UIFigure, 'numeric');
            app.Step.Value = 0.005;
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = 'Интенсивность';
            app.Intensity = uieditfield(app.UIFigure, 'numeric');
            app.Intensity.Value = 0.01;
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = 'Количество точек';
            app.Quantity = uieditfield(app.UIFigure, 'numeric');
            app.Quantity.Value = 40000;
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X =';
            app.PointX = uieditfield(app.UIFigure, 'numeric');
            app.PointX.Value = 1;
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = ';
            app.PointY = uieditfield(app.UIFigure, 'numeric');
            app.PointY.Value = 1.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_prj_KN301_Artsybashev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end