classdef lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Button           matlab.ui.control.Button
        CheckBox         matlab.ui.control.CheckBox
        TEditField       matlab.ui.control.NumericEditField
        TEditFieldLabel  matlab.ui.control.Label
        x0EditField      matlab.ui.control.NumericEditField
        x_0Label         matlab.ui.control.Label
        alphaEditField   matlab.ui.control.NumericEditField
        alphaLabel       matlab.ui.control.Label
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a = app.alphaEditField.Value;
            x0 = app.x0EditField.Value;
            T = app.TEditField.Value;

            x = linspace(0, 1);
            cla(app.UIAxes);
            plot(app.UIAxes, x, a*x.*(1-x), "red");
            hold(app.UIAxes);
            plot(app.UIAxes, x, x, "blue");
            
            xArray = zeros(1, T);
            for i = 1:T
                x1 = a*x0*(1-x0);
                if (app.CheckBox.Value == 0)
                    plot(app.UIAxes, [x0 x0], [x0 x1], "black");
                    plot(app.UIAxes, [x0 x1], [x1 x1], "black");
                end
                x0 = x1;
            end

            if (app.CheckBox.Value == 1)
                for i = T:(2*T-1)
                    x1 = a*x0*(1-x0);
                    plot(app.UIAxes, [x0 x0], [x0 x1], "black");
                    plot(app.UIAxes, [x0 x1], [x1 x1], "black");
                    x0 = x1;
                end
            end
            hold(app.UIAxes);
            
            plot(app.UIAxes2, 1:1:T, xArray, ".-");
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Лестница Ламерея')
            xlabel(app.UIAxes, 'X_t')
            ylabel(app.UIAxes, 'X_{t+1}')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Временной ряд')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'X')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.UIAxes2.YTick = [0 0.25 0.5 0.75 1];
            app.UIAxes2.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'};
            app.alphaLabel = uilabel(app.UIFigure);
            app.alphaLabel.Text = 'alpha = ';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_0Label = uilabel(app.UIFigure);
            app.x_0Label.Text = 'x0 =';
            app.x0EditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T = ';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'Пропустить переход';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end